classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AnalyseButton               matlab.ui.control.Button
        FinishTimeTextArea          matlab.ui.control.TextArea
        FinishTimeTextAreaLabel     matlab.ui.control.Label
        ModulationOptDropDown       matlab.ui.control.DropDown
        ModulationOptDropDownLabel  matlab.ui.control.Label
        FrequencyHzEditField        matlab.ui.control.EditField
        FrequencyHzEditFieldLabel   matlab.ui.control.Label
        BasicConfigurationsLabel    matlab.ui.control.Label
        FinishButton                matlab.ui.control.Button
        StartRecordButton           matlab.ui.control.Button
        TextDisplayTextArea         matlab.ui.control.TextArea
        TextDisplayTextAreaLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        config % parameter configuration
        recorder % recorder
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.config.sample_rate = 8000;
            app.config.f1 = 100;
            app.config.f2 = 900;
            app.config.sps = 480;
            app.recorder = audiorecorder(app.config.sample_rate,8,1);
        end

        % Button pushed function: StartRecordButton
        function StartRecordButtonPushed(app, event)
            record(app.recorder);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            stop(app.recorder);
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            play_seq = getaudiodata(app.recorder);
            disp('abc');
            [message_out] = Analyse(play_seq,app.config);
            app.FinishTimeTextArea.Value = message_out;
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            app.config.frequency = str2double(app.FrequencyHzEditField.Value);
        end

        % Value changed function: ModulationOptDropDown
        function ModulationOptDropDownValueChanged(app, event)
            value = app.ModulationOptDropDown.Value;
            if(value == "16QAM")
                app.config.map_option = 0;
            else
                app.config.map_option= 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextDisplayTextAreaLabel = uilabel(app.UIFigure);
            app.TextDisplayTextAreaLabel.Text = 'Text Display  ';
            app.TextDisplayTextArea = uitextarea(app.UIFigure);
            app.StartRecordButton = uibutton(app.UIFigure, 'push');
            app.StartRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordButtonPushed, true);
            app.StartRecordButton.Text = {'Start Record'; ''};
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.BasicConfigurationsLabel = uilabel(app.UIFigure);
            app.BasicConfigurationsLabel.Text = {'Basic Configurations'; ''};
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency/Hz';
            app.FrequencyHzEditField = uieditfield(app.UIFigure, 'text');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.ModulationOptDropDownLabel = uilabel(app.UIFigure);
            app.ModulationOptDropDownLabel.Text = {'Modulation Opt'; ''};
            app.ModulationOptDropDown = uidropdown(app.UIFigure);
            app.ModulationOptDropDown.Items = {'16QAM', '64QAM'};
            app.ModulationOptDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationOptDropDownValueChanged, true);
            app.ModulationOptDropDown.Value = '16QAM';
            app.FinishTimeTextAreaLabel = uilabel(app.UIFigure);
            app.FinishTimeTextAreaLabel.Text = 'Finish Time';
            app.FinishTimeTextArea = uitextarea(app.UIFigure);
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end